classdef AdminPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        AllCandidatesDetailsButton  matlab.ui.control.Button
        AddAdminButton              matlab.ui.control.Button
        EvaluationButton            matlab.ui.control.Button
        RunningInterviewsButton     matlab.ui.control.Button
        InterviewerDetailsButton    matlab.ui.control.Button
        AdminDashboardLabel         matlab.ui.control.Label
        CandidateDetailsButton      matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CandidateDetailsButton
        function CandidateDetailsNextPage(app, event)
            AdminPageCandidatedetails
            app.UIFigure.Visible="off";
        end

        % Button pushed function: EvaluationButton
        function EvaluationNextPage(app, event)
            AdminPageEvaluation
            app.UIFigure.Visible="off";
        end

        % Button pushed function: InterviewerDetailsButton
        function InterviewerDetailsNextPage(app, event)
            AdminPageInterviewerdetails
            app.UIFigure.Visible="off";
        end

        % Button pushed function: RunningInterviewsButton
        function RunninginterviewsNextPage(app, event)
            AdminPageRunninginterviews
            app.UIFigure.Visible="off";
        end

        % Button pushed function: AddAdminButton
        function AddAdminNextPage(app, event)
            AdminPageAddAdmin
            app.UIFigure.Visible="off";
        end

        % Button pushed function: AllCandidatesDetailsButton
        function AllCandidatesDetailsNextPage(app, event)
            objj = AdminPageAllCandidatesDetails;
            [E,objj.UITable.Data] = getAllCandidates();
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No data available";
            else
                app.Label.Text="";
            end
            objj
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.CandidateDetailsButton = uibutton(app.UIFigure, 'push');
            app.CandidateDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetailsNextPage, true);
            app.CandidateDetailsButton.Text = 'Candidate Details';
            app.AdminDashboardLabel = uilabel(app.UIFigure);
            app.AdminDashboardLabel.Text = 'Admin Dashboard';
            app.InterviewerDetailsButton = uibutton(app.UIFigure, 'push');
            app.InterviewerDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerDetailsNextPage, true);
            app.InterviewerDetailsButton.Text = 'Interviewer Details';
            app.RunningInterviewsButton = uibutton(app.UIFigure, 'push');
            app.RunningInterviewsButton.ButtonPushedFcn = createCallbackFcn(app, @RunninginterviewsNextPage, true);
            app.RunningInterviewsButton.Text = 'Running Interviews';
            app.EvaluationButton = uibutton(app.UIFigure, 'push');
            app.EvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluationNextPage, true);
            app.EvaluationButton.Text = 'Evaluation';
            app.AddAdminButton = uibutton(app.UIFigure, 'push');
            app.AddAdminButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminNextPage, true);
            app.AddAdminButton.Text = 'Add Admin';
            app.AllCandidatesDetailsButton = uibutton(app.UIFigure, 'push');
            app.AllCandidatesDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @AllCandidatesDetailsNextPage, true);
            app.AllCandidatesDetailsButton.Text = 'All Candidates Details';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end