classdef AdminPageInterviewerdetails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        DepartmentDropDown          matlab.ui.control.DropDown
        DepartmentDropDownLabel     matlab.ui.control.Label
        EntertheNameEditField       matlab.ui.control.EditField
        EntertheNameEditFieldLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        PreviousButton              matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        MailIDLabel                 matlab.ui.control.Label
        DomainLabel                 matlab.ui.control.Label
        DepartmentLabel             matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        SearchButton                matlab.ui.control.Button
        InterviewerDetailsLabel     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchInterviewerDetails(app, event)
            Name=app.EntertheNameEditField.Value;
            Dept=app.DepartmentDropDown.Value;
            if Dept == "Human Resource"
                Dept = "HR";
            elseif Dept == "Technical"
                Dept = "TECH";
            else
                Dept = "MG";
            end
            [E,data] = interviewerDetail(Name,Dept);
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No Interviewer Found";
            else
                if isempty(data)
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="No Interviewer Found";
                else
                    app.Label.Text="";
                    app.Label_2.Text =data.Name{1};
                    app.Label_4.Text=data.Department{1};
                    app.Label_5.Text=data.Domain{1};
                    app.Label_6.Text=data.Email{1};
                    app.Label_3.Text=data.Status{1};
                end
            end
        end

        % Button pushed function: PreviousButton
        function InterviewerDetailsPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.InterviewerDetailsLabel = uilabel(app.GridLayout);
            app.InterviewerDetailsLabel.Text = 'Interviewer Details';
            app.SearchButton = uibutton(app.GridLayout, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchInterviewerDetails, true);
            app.SearchButton.Text = 'Search';
            app.NameLabel = uilabel(app.GridLayout);
            app.NameLabel.Text = 'Name:';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status:';
            app.DepartmentLabel = uilabel(app.GridLayout);
            app.DepartmentLabel.Text = 'Department:';
            app.DomainLabel = uilabel(app.GridLayout);
            app.DomainLabel.Text = 'Domain:';
            app.MailIDLabel = uilabel(app.GridLayout);
            app.MailIDLabel.Text = 'Mail ID:';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerDetailsPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.EntertheNameEditFieldLabel = uilabel(app.GridLayout);
            app.EntertheNameEditFieldLabel.Text = 'Enter the Name';
            app.EntertheNameEditField = uieditfield(app.GridLayout, 'text');
            app.DepartmentDropDownLabel = uilabel(app.GridLayout);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.GridLayout);
            app.DepartmentDropDown.Items = {'<Select>', 'Human Resource', 'Technical', 'Manager'};
            app.DepartmentDropDown.Value = '<Select>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageInterviewerdetails

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end