classdef InterviewerAddReview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PreviousPageButton        matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        StudentIDEditFieldLabel   matlab.ui.control.Label
        StudentIDEditField        matlab.ui.control.EditField
        NOGOButton                matlab.ui.control.Button
        GOButton                  matlab.ui.control.Button
        ReviewEditFieldLabel      matlab.ui.control.Label
        ReviewEditField           matlab.ui.control.EditField
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        AddReviewForStudentLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = public)
        InterviewerName string % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function InterviewerPushGo(app, event)
            err=addReview(app.StudentIDEditField.Value,app.InterviewerName,app.ReviewEditField.Value,"Go")
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't Add review please check details";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Review has been added  ";
            end
        end

        % Button pushed function: NOGOButton
        function InterviewerPushNOGo(app, event)
            err=addReview(app.StudentIDEditField.Value,app.InterviewerName,app.ReviewEditField.Value,"No Go")
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't Add review please check details";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Review has been added  ";
            end
        end

        % Button pushed function: PreviousPageButton
        function PreviousPageButtonPushed(app, event)
            obj=InterviewerPage;
            obj.InterviewerName = app.InterviewerName;
            obj
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.AddReviewForStudentLabel = uilabel(app.UIFigure);
            app.AddReviewForStudentLabel.Text = 'Add Review For Student';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.ReviewEditField = uieditfield(app.UIFigure, 'text');
            app.ReviewEditFieldLabel = uilabel(app.UIFigure);
            app.ReviewEditFieldLabel.Text = 'Review';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerPushGo, true);
            app.GOButton.Text = 'GO';
            app.NOGOButton = uibutton(app.UIFigure, 'push');
            app.NOGOButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerPushNOGo, true);
            app.NOGOButton.Text = 'NOGO';
            app.StudentIDEditField = uieditfield(app.UIFigure, 'text');
            app.StudentIDEditFieldLabel = uilabel(app.UIFigure);
            app.StudentIDEditFieldLabel.Text = 'Student ID';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PreviousPageButton = uibutton(app.UIFigure, 'push');
            app.PreviousPageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPageButtonPushed, true);
            app.PreviousPageButton.Text = 'Previous Page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerAddReview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end