classdef InterviewerPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AddReviewForStudentButton  matlab.ui.control.Button
        UpdateYourStatusButton     matlab.ui.control.Button
        ScheduledInterviewsButton  matlab.ui.control.Button
        InterviewerPageLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        InterviewerName string % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScheduledInterviewsButton
        function InterviewerScheduledInterviewsNextPage(app, event)
            
           obj =  InterviewerScheduledInterviews;
           obj.UITable.Data=getScheduledInterviews(app.InterviewerName);
           obj
        end

        % Button pushed function: UpdateYourStatusButton
        function InterviewerUpdateStatusNextPage(app, event)
            obj=InterviewerUpdateStatus;
            obj.InterviewerName=app.InterviewerName;
            obj
            app.UIFigure.Visible="off"
        end

        % Button pushed function: AddReviewForStudentButton
        function InterviewerAddReviewNextPage(app, event)
            obj=InterviewerAddReview;
            obj.InterviewerName=app.InterviewerName;
            obj
            app.UIFigure.Visible="off"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterviewerPageLabel = uilabel(app.UIFigure);
            app.InterviewerPageLabel.Text = 'Interviewer Page';
            app.ScheduledInterviewsButton = uibutton(app.UIFigure, 'push');
            app.ScheduledInterviewsButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerScheduledInterviewsNextPage, true);
            app.ScheduledInterviewsButton.Text = 'Scheduled Interviews';
            app.UpdateYourStatusButton = uibutton(app.UIFigure, 'push');
            app.UpdateYourStatusButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerUpdateStatusNextPage, true);
            app.UpdateYourStatusButton.Text = 'Update Your Status';
            app.AddReviewForStudentButton = uibutton(app.UIFigure, 'push');
            app.AddReviewForStudentButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerAddReviewNextPage, true);
            app.AddReviewForStudentButton.Text = 'Add Review For Student ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end